classdef finalProjectAudioEqualizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Label                   matlab.ui.control.Label
        ProcessandPlayButton    matlab.ui.control.Button
        PlayOriginalFileButton  matlab.ui.control.Button
        LoadAudioFileButton     matlab.ui.control.Button
        kHzKnob_5               matlab.ui.control.Knob
        kHzLabel_3              matlab.ui.control.Label
        kHzKnob_4               matlab.ui.control.Knob
        kHzLabel_2              matlab.ui.control.Label
        kHzKnob_3               matlab.ui.control.Knob
        kHzLabel                matlab.ui.control.Label
        kHzKnob_2               matlab.ui.control.Knob
        kHzKnob_2Label          matlab.ui.control.Label
        kHzKnob                 matlab.ui.control.Knob
        kHzKnobLabel            matlab.ui.control.Label
        HzKnob_5                matlab.ui.control.Knob
        HzLabel_4               matlab.ui.control.Label
        HzKnob_4                matlab.ui.control.Knob
        HzLabel_3               matlab.ui.control.Label
        HzKnob_3                matlab.ui.control.Knob
        HzLabel_2               matlab.ui.control.Label
        HzKnob_2                matlab.ui.control.Knob
        HzLabel                 matlab.ui.control.Label
        HzKnob                  matlab.ui.control.Knob
        HzKnobLabel             matlab.ui.control.Label
        ResetButton             matlab.ui.control.Button
        FreqPlot                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        noiseyBoi;
        Fs = 0;
        freqBands = [31.25,62.5,125,250,500,1000,2000,4000,8000,16000]
        gainz = zeros(1,10);
        N = 0;
        H = 0;
    end
    
    methods (Access = private)
        
        function updateApp(app)
            freqResolution = 3;
            app.N = ceil(app.Fs/freqResolution);
            freqs = (0:app.N-1)'/app.N;
            %creates the discrete part of the response
            discBand(1:10) = app.freqBands/app.Fs;
            discBand(11:20) = 1-(flip(discBand(1:10)));
            %creates a mirrored look of the discrete graph
            mirrorGainz(1:10) = 10.^(app.gainz/20);
            mirrorGainz(11:20) = flip(10.^(app.gainz/20));
            %interpolates the discrete and mirrored arrays
            app.H = interp1(discBand, mirrorGainz ,freqs,"linear","extrap");
            plot(app.FreqPlot, freqs*app.Fs, 20*log10(app.H));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.HzKnob.Value = 0;
            app.HzKnob_2.Value = 0;
            app.HzKnob_3.Value = 0;
            app.HzKnob_4.Value = 0;
            app.HzKnob_5.Value = 0;
            app.kHzKnob.Value = 0;
            app.kHzKnob_2.Value = 0;
            app.kHzKnob_3.Value = 0;
            app.kHzKnob_4.Value = 0;
            app.kHzKnob_5.Value = 0;
            app.gainz(1:10) = 0;
            clear sound;
            updateApp(app);
        end

        % Button pushed function: LoadAudioFileButton
        function LoadAudioFileButtonPushed(app, event)
            try
                file = uigetfile('*.wav','*.mp3');
                app.Label.Text = file;
                [app.noiseyBoi, app.Fs] = audioread(file);   
            catch ME
                app.Label.Text = "Invalid file or path. Exit Code 0."; 
            end
        end

        % Button pushed function: PlayOriginalFileButton
        function PlayOriginalFileButtonPushed(app, event)
            sound(app.noiseyBoi, app.Fs);
        end

        % Value changing function: HzKnob
        function HzKnobValueChanging(app, event)
            app.gainz(1) = event.Value;
            updateApp(app);
        end

        % Value changing function: HzKnob_2
        function HzKnob_2ValueChanging(app, event)
            app.gainz(2) = event.Value;
            updateApp(app);
        end

        % Value changing function: HzKnob_3
        function HzKnob_3ValueChanging(app, event)
            app.gainz(3) = event.Value;
            updateApp(app);
        end

        % Value changing function: HzKnob_4
        function HzKnob_4ValueChanging(app, event)
            app.gainz(4) = event.Value;
            updateApp(app);
        end

        % Value changing function: HzKnob_5
        function HzKnob_5ValueChanging(app, event)
            app.gainz(5) = event.Value;
            updateApp(app);
        end

        % Value changing function: kHzKnob
        function kHzKnobValueChanging(app, event)
            app.gainz(6) = event.Value;
            updateApp(app);
        end

        % Value changing function: kHzKnob_2
        function kHzKnob_2ValueChanging(app, event)
            app.gainz(7) = event.Value;
            updateApp(app);
        end

        % Value changing function: kHzKnob_3
        function kHzKnob_3ValueChanging(app, event)
            app.gainz(8) = event.Value;
            updateApp(app);
        end

        % Value changing function: kHzKnob_4
        function kHzKnob_4ValueChanging(app, event)
            app.gainz(9) = event.Value;
            updateApp(app);
        end

        % Value changing function: kHzKnob_5
        function kHzKnob_5ValueChanging(app, event)
            app.gainz(10) = event.Value;
            updateApp(app);
        end

        % Button pushed function: ProcessandPlayButton
        function ProcessandPlayButtonPushed(app, event)
            % clears current loaded sound
            clear sound;
            % sets impluse using ifft()
            impulse = real(ifft(app.H)); 
            % assigns the variables to the values
            assignin("base",'impulse', impulse);
            assignin("base",'audiofile', app.noiseyBoi);
            % uses blackman function to calculate impulse
            impulse = circshift(impulse, length(impulse)/2);
            impulse = impulse .* blackman(app.N);
            filtered = conv2(impulse,app.noiseyBoi);
            % assigns the filtered variable
            assignin("base",'filtered', filtered);
            sound(filtered,app.Fs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FreqPlot = uiaxes(app.UIFigure);
            title(app.FreqPlot, 'Frequency Response')
            xlabel(app.FreqPlot, 'Frequency')
            ylabel(app.FreqPlot, '|H(f)|')
            app.FreqPlot.XLim = [0 45000];
            app.FreqPlot.YLim = [-60 60];
            app.FreqPlot.Tag = 'FreqPlot';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.HzKnobLabel = uilabel(app.UIFigure);
            app.HzKnobLabel.Text = '31.25Hz';
            app.HzKnob = uiknob(app.UIFigure, 'continuous');
            app.HzKnob.Limits = [-60 60];
            app.HzKnob.ValueChangingFcn = createCallbackFcn(app, @HzKnobValueChanging, true);
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = '62.5Hz';
            app.HzKnob_2 = uiknob(app.UIFigure, 'continuous');
            app.HzKnob_2.Limits = [-60 60];
            app.HzKnob_2.ValueChangingFcn = createCallbackFcn(app, @HzKnob_2ValueChanging, true);
            app.HzLabel_2 = uilabel(app.UIFigure);
            app.HzLabel_2.Text = '125Hz';
            app.HzKnob_3 = uiknob(app.UIFigure, 'continuous');
            app.HzKnob_3.Limits = [-60 60];
            app.HzKnob_3.ValueChangingFcn = createCallbackFcn(app, @HzKnob_3ValueChanging, true);
            app.HzLabel_3 = uilabel(app.UIFigure);
            app.HzLabel_3.Text = '250Hz';
            app.HzKnob_4 = uiknob(app.UIFigure, 'continuous');
            app.HzKnob_4.Limits = [-60 60];
            app.HzKnob_4.ValueChangingFcn = createCallbackFcn(app, @HzKnob_4ValueChanging, true);
            app.HzLabel_4 = uilabel(app.UIFigure);
            app.HzLabel_4.Text = '500Hz';
            app.HzKnob_5 = uiknob(app.UIFigure, 'continuous');
            app.HzKnob_5.Limits = [-60 60];
            app.HzKnob_5.ValueChangingFcn = createCallbackFcn(app, @HzKnob_5ValueChanging, true);
            app.kHzKnobLabel = uilabel(app.UIFigure);
            app.kHzKnobLabel.Text = '1kHz';
            app.kHzKnob = uiknob(app.UIFigure, 'continuous');
            app.kHzKnob.Limits = [-60 60];
            app.kHzKnob.ValueChangingFcn = createCallbackFcn(app, @kHzKnobValueChanging, true);
            app.kHzKnob_2Label = uilabel(app.UIFigure);
            app.kHzKnob_2Label.Text = '2kHz';
            app.kHzKnob_2 = uiknob(app.UIFigure, 'continuous');
            app.kHzKnob_2.Limits = [-60 60];
            app.kHzKnob_2.ValueChangingFcn = createCallbackFcn(app, @kHzKnob_2ValueChanging, true);
            app.kHzLabel = uilabel(app.UIFigure);
            app.kHzLabel.Text = '4kHz';
            app.kHzKnob_3 = uiknob(app.UIFigure, 'continuous');
            app.kHzKnob_3.Limits = [-60 60];
            app.kHzKnob_3.ValueChangingFcn = createCallbackFcn(app, @kHzKnob_3ValueChanging, true);
            app.kHzLabel_2 = uilabel(app.UIFigure);
            app.kHzLabel_2.Text = '8kHz';
            app.kHzKnob_4 = uiknob(app.UIFigure, 'continuous');
            app.kHzKnob_4.Limits = [-60 60];
            app.kHzKnob_4.ValueChangingFcn = createCallbackFcn(app, @kHzKnob_4ValueChanging, true);
            app.kHzLabel_3 = uilabel(app.UIFigure);
            app.kHzLabel_3.Text = '16kHz';
            app.kHzKnob_5 = uiknob(app.UIFigure, 'continuous');
            app.kHzKnob_5.Limits = [-60 60];
            app.kHzKnob_5.ValueChangingFcn = createCallbackFcn(app, @kHzKnob_5ValueChanging, true);
            app.LoadAudioFileButton = uibutton(app.UIFigure, 'push');
            app.LoadAudioFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAudioFileButtonPushed, true);
            app.LoadAudioFileButton.Text = 'Load Audio File';
            app.PlayOriginalFileButton = uibutton(app.UIFigure, 'push');
            app.PlayOriginalFileButton.ButtonPushedFcn = createCallbackFcn(app, @PlayOriginalFileButtonPushed, true);
            app.PlayOriginalFileButton.Text = 'Play Original File';
            app.ProcessandPlayButton = uibutton(app.UIFigure, 'push');
            app.ProcessandPlayButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessandPlayButtonPushed, true);
            app.ProcessandPlayButton.Text = 'Process and Play';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = finalProjectAudioEqualizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end